classdef MainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        DetectButton  matlab.ui.control.Button
    end


    methods (Access = private)
    
        function [G] = MainAlg(app)
    % Select and scan in Image
    [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
    fullname = [filepath, filename];
    ImageFile = imread(fullname);
    test = ImageFile;
    test = imrotate(test,270);
    rgb = double(test) / 255;

    % Convert to Grayscale
    G = rgb2gray(rgb);

    % Darken Grayscale
    D = imadjust(G);

    % RGB to HSV
    HSV = rgb2hsv(rgb);

    % RGB to HSL
    HSL = rgb2hsl(rgb);

    % Yellow Masking
    [Yellow, YellowMaskedRGBImage] = createMask(rgb, 1, 0.5, 0.2, 1, .85, 1);

    % White Masking
    [White, WhiteMaskedRGBImage] = createMask(rgb, 0, 1, 0, .08, .95, 1);

    % Combined Mask
    C = Yellow + White;

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    % Slight Gaussian blur, this reduces number of detected edges
    Combined_Image_Gaussian_Blur = imgaussfilt(C,2);

    % Apply Canny Edge Detection
    Edge_Detection_Image = edge(Combined_Image_Gaussian_Blur, 'canny');

    % Perform ROI
    % don't know how to handle this, should we make dynamic for pictures
    % or just hard code for our images?
    % these params are xmin yim width height
    [Cropped_Image,rect] = SelectROI(app, Edge_Detection_Image);

    % Perform Hough Transform
    [H,T,R] = hough(Cropped_Image);
    P = houghpeaks(H,5,'threshold',ceil(0.3*max(H(:))));
    x = T(P(:,2)); y = R(P(:,1));
    lines = houghlines(Cropped_Image,T,R,P,'FillGap',5,'MinLength',7);
    subplot(1,2,1), imshow(test), title('Original'),
    subplot(1,2,2),imshow(G), title('Line Detection'), hold on
    max_len = 0;
                
    for k = 1:length(lines)
       xy = [lines(k).point1; lines(k).point2];
       plot(xy(:,1)+(rect(1)),xy(:,2)+(rect(2)),'LineWidth',2,'Color','green');

       % Plot beginnings and ends of lines
       %plot(xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow');
       %plot(xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red');
    end
        end
        %function to crop image based on rectangle selected by user
        function [croppedImage,rect] = SelectROI(app,test)
            [croppedImage,rect] = imcrop(test);
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            MainAlg(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainWindow

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end